classdef simpleCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        EnterButton                     matlab.ui.control.Button
        TextArea_Num2                   matlab.ui.control.TextArea
        TextArea_Num1                   matlab.ui.control.TextArea
        PlottingbuttonGroupButtonGroup  matlab.ui.container.ButtonGroup
        EquationButton                  matlab.ui.control.RadioButton
        CosineButton                    matlab.ui.control.RadioButton
        SineButton                      matlab.ui.control.RadioButton
        ResultantButton                 matlab.ui.control.RadioButton
        XYAxisButton                    matlab.ui.control.RadioButton
        YAxisButton                     matlab.ui.control.RadioButton
        XAxisButton                     matlab.ui.control.RadioButton
        EqualButton_2                   matlab.ui.control.Button
        ErrormsgLabel_2                 matlab.ui.control.Label
        ErrorConsoleLabel_2             matlab.ui.control.Label
        Label_resultMatrix              matlab.ui.control.Label
        ResultLabel_2                   matlab.ui.control.Label
        OperationsListBox_2             matlab.ui.control.ListBox
        OpLabel_2                       matlab.ui.control.Label
        TextArea_yaxis                  matlab.ui.control.TextArea
        TextArea_xaxis                  matlab.ui.control.TextArea
        YAxisLabel                      matlab.ui.control.Label
        XAxisLabel                      matlab.ui.control.Label
        EnterEquationLabel              matlab.ui.control.Label
        EqualButton                     matlab.ui.control.Button
        OperationsListBox               matlab.ui.control.ListBox
        OperationsListBoxLabel          matlab.ui.control.Label
        ResultLabel                     matlab.ui.control.Label
        Label_equal                     matlab.ui.control.Label
        OpLabel                         matlab.ui.control.Label
        ErrormsgLabel                   matlab.ui.control.Label
        ErrorConsoleLabel               matlab.ui.control.Label
        SimpleCalculatorLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            global selectedOperation1;
            global selectedOperation2;
            global appPlot;
        end

        % Value changed function: OperationsListBox
        function OperationsListBoxValueChanged(app, event)
            global selectedOperation1;
            selectedOperation1 = app.OperationsListBox.Value;
            app.OpLabel.Text = sprintf('%s', selectedOperation1);    
        end

        % Value changed function: OperationsListBox_2
        function OperationsListBox_2ValueChanged(app, event)
            global selectedOperation2;
            selectedOperation2 = app.OperationsListBox_2.Value;
            app.OpLabel_2.Text = sprintf('%s', selectedOperation2);
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            global selectedOperation1;
            num1_str = app.TextArea_Num1.Value;
            num2_str = app.TextArea_Num2.Value;
            
            %convert the number string to double
            num1 = str2double(num1_str);
            num2 = str2double(num2_str);
            
            %decleare result variable
            result =NaN;
            
            %check if the convertion succeded and the entered values are
            %numbers
            if ~isnan(num1) && ~isnan(num2)
                if (num2==0) && (strcmp(selectedOperation1,'/'))
                    app.ErrormsgLabel.Text = 'Divide by Zero is Not Applicable';
                else
                    switch selectedOperation1
                        case '/'
                            result = num1 / num2;
                        case '+'
                            result = num1 + num2;
                        case '-'
                            result = num1 - num2;
                        case '*'
                            result = num1 * num2;
                    end
                         app.ResultLabel.Text = sprintf('%0.2f',result); 
                end
            else
                app.ErrormsgLabel.Text = 'Please Enter Numeric Input';
            end
            
        end

        % Button pushed function: EqualButton_2
        function EqualButton_2Pushed(app, event)
            global selectedOperation2;
            matrix1_str = app.TextArea_xaxis.Value;
            matrix2_str = app.TextArea_yaxis.Value;
          
            function vec = string2Vector(str)
                str = erase(str, {'[', ']'});
                str = split(str, {' ', ',', ';'});
                vec = str2double(str);
                vec = vec(~isnan(vec));
            end
            
            matrix1 = string2Vector(matrix1_str);
            matrix2 = string2Vector(matrix2_str);
            
            if numel(matrix1) ~= numel(matrix2)
                app.ErrormsgLabel_2.Text = 'Matrices must have the same number of elements.';
                return;  % Exit the function if the matrices have different sizes
            end
            result = NaN(size(matrix1));
            
            % Check if the conversion succeeded and the entered values are matrices
            if ~isempty(matrix1) && ~isempty(matrix2)
                if any(matrix2(:) == 0) && (strcmp(selectedOperation2, '/'))
                    app.ErrormsgLabel_2.Text = 'Divide by Zero is Not Applicable';
                else
                    switch selectedOperation2
                        case '/'
                            % Check for division by zero
                            result = matrix1 ./ matrix2;
                        case '+'
                            result = matrix1 + matrix2;
                        case '-'
                            result = matrix1 - matrix2;
                        case '*'
                            result = matrix1 .* matrix2;
                    end
                    app.Label_resultMatrix.Text = sprintf('%s', mat2str(result));
                end
            else
                app.ErrormsgLabel_2.Text = 'Please Enter Numeric Input';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            global appPlot;
            
            %Radio buttons selected Option Variable
            selectedOption = app.PlottingbuttonGroupButtonGroup.SelectedObject;         
            
            function vec = string2Vector(str)
                str = erase(str, {'[', ']'});
                str = split(str, {' ', ',', ';'});
                vec = str2double(str);
                vec = vec(~isnan(vec));
            end  
            
            xValues = string2Vector(app.TextArea_xaxis.Value);
            yValues = string2Vector(app.TextArea_yaxis.Value);
            
            %Angle Decleration for sin and cos plots
            a = 0 : 0.1 : 2*pi;
            
            if selectedOption == app.XYAxisButton
                 % * is the point and k is the color "black"
                 appPlot = plot(app.UIAxes,xValues,yValues,'*k');
            elseif selectedOption == app.XAxisButton
                % y axis will be zero
                appPlot = plot(app.UIAxes,xValues,0,'*k');   
            elseif selectedOption == app.YAxisButton
                % x will be zero
                appPlot = plot(app.UIAxes,0,yValues,'*k');
            elseif selectedOption == app.ResultantButton
                rsultant = xValues + yValues;
                appPlot = plot(app.UIAxes,rsultant,'*k');
            elseif selectedOption == app.SineButton
                appPlot = plot(app.UIAxes,sin(a));
            elseif selectedOption == app.CosineButton
                appPlot = plot(app.UIAxes,cos(a));
            elseif selectedOption == app.EquationButton
                %equation string is an input from user
                equation_str = app.EditField.Value;
                %remove y and = to can use str2func
                equation_str_1 = erase(equation_str,{'y','='});
                %square brackets [] used to concate 
                equation = str2func(['@(x)' equation_str_1]);
                yValues = equation(xValues);
                appPlot = plot(app.UIAxes,xValues,yValues,'LineWidth',2);
            else
                app.ErrormsgLabel_2.Text = 'Invalid Plotting Option';
            end
        end

        % Button down function: PlottingbuttonGroupButtonGroup
        function PlottingbuttonGroupButtonGroupButtonDown(app, event)
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            global appPlot;
            delete(appPlot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.ErrorConsoleLabel = uilabel(app.UIFigure);
            app.ErrorConsoleLabel.Text = 'Error Console';
            app.ErrormsgLabel = uilabel(app.UIFigure);
            app.ErrormsgLabel.Text = 'Error msg';
            app.OpLabel = uilabel(app.UIFigure);
            app.OpLabel.Text = 'Op';
            app.Label_equal = uilabel(app.UIFigure);
            app.Label_equal.Text = '=';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.OperationsListBoxLabel = uilabel(app.UIFigure);
            app.OperationsListBoxLabel.Text = 'Operations';
            app.OperationsListBox = uilistbox(app.UIFigure);
            app.OperationsListBox.Items = {'+', '/', '*', '-', ''};
            app.OperationsListBox.ValueChangedFcn = createCallbackFcn(app, @OperationsListBoxValueChanged, true);
            app.OperationsListBox.Value = '-';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.EnterEquationLabel = uilabel(app.UIFigure);
            app.EnterEquationLabel.Text = 'Enter Equation';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X-Axis';
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y-Axis';
            app.TextArea_xaxis = uitextarea(app.UIFigure);
            app.TextArea_yaxis = uitextarea(app.UIFigure);
            app.OpLabel_2 = uilabel(app.UIFigure);
            app.OpLabel_2.Text = 'Op';
            app.OperationsListBox_2 = uilistbox(app.UIFigure);
            app.OperationsListBox_2.Items = {'+', '/', '*', '-'};
            app.OperationsListBox_2.ValueChangedFcn = createCallbackFcn(app, @OperationsListBox_2ValueChanged, true);
            app.OperationsListBox_2.Value = '+';
            app.ResultLabel_2 = uilabel(app.UIFigure);
            app.ResultLabel_2.Text = 'Result';
            app.Label_resultMatrix = uilabel(app.UIFigure);
            app.Label_resultMatrix.Text = '';
            app.ErrorConsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorConsoleLabel_2.Text = 'Error Console';
            app.ErrormsgLabel_2 = uilabel(app.UIFigure);
            app.ErrormsgLabel_2.Text = 'Error msg';
            app.EqualButton_2 = uibutton(app.UIFigure, 'push');
            app.EqualButton_2.ButtonPushedFcn = createCallbackFcn(app, @EqualButton_2Pushed, true);
            app.EqualButton_2.Text = 'Equal';
            app.PlottingbuttonGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingbuttonGroupButtonGroup.Title = 'Plotting button Group';
            app.PlottingbuttonGroupButtonGroup.ButtonDownFcn = createCallbackFcn(app, @PlottingbuttonGroupButtonGroupButtonDown, true);
            app.XAxisButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.XAxisButton.Text = 'X-Axis';
            app.XAxisButton.Value = true;
            app.YAxisButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.YAxisButton.Text = 'Y-Axis';
            app.XYAxisButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.XYAxisButton.Text = 'XY-Axis';
            app.ResultantButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.ResultantButton.Text = 'Resultant';
            app.SineButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.SineButton.Text = 'Sine';
            app.CosineButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.EquationButton = uiradiobutton(app.PlottingbuttonGroupButtonGroup);
            app.EquationButton.Text = 'Equation';
            app.TextArea_Num1 = uitextarea(app.UIFigure);
            app.TextArea_Num2 = uitextarea(app.UIFigure);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end