classdef HappySadapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GetImagButton                matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        Below60HappyAbove60SadLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImagButton
        function GetImagButtonPushed(app, event)
            value = app.EditField.Value;
            %remove the labels of the plot
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            if value < 60
                happyImag = imread("happy.jpg");
                imshow(happyImag,'Parent',app.UIAxes);
                title(app.UIAxes,'Happy!');
            elseif value > 60
                sadImag = imread("sad.jpg");
                imshow(sadImag,'Parent',app.UIAxes);
                title(app.UIAxes,'Sad!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Below60HappyAbove60SadLabel = uilabel(app.UIFigure);
            app.Below60HappyAbove60SadLabel.Text = {'Below 60 Happy :)'; 'Above 60 Sad :('};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.GetImagButton = uibutton(app.UIFigure, 'push');
            app.GetImagButton.ButtonPushedFcn = createCallbackFcn(app, @GetImagButtonPushed, true);
            app.GetImagButton.Text = 'Get Imag';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HappySadapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end