classdef TrafficLight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnterDelayTimeInSecondsEditField  matlab.ui.control.NumericEditField
        EnterDelayTimeInSecondsLabel  matlab.ui.control.Label
        LampGreen                     matlab.ui.control.Lamp
        Lamp3Label                    matlab.ui.control.Label
        LampYellow                    matlab.ui.control.Lamp
        Lamp2Label                    matlab.ui.control.Label
        LampRed                       matlab.ui.control.Lamp
        LampLabel                     matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        ManualButton                  matlab.ui.control.Button
        AutomaticButton               matlab.ui.control.Button
        RedButton                     matlab.ui.control.Button
        YellowButton                  matlab.ui.control.Button
        GreenButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
        global a;
            a = arduino('COM3','Uno');
            configurePin(a,'D0','DigitalOutput');
            configurePin(a,'D1','DigitalOutput');
            configurePin(a,'D2','DigitalOutput');
        global ManualFlag;
            ManualFlag = 0;
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            global ManualFlag;
            app.GreenButton.Enable = 'on';
            app.YellowButton.Enable = 'on';
            app.RedButton.Enable = 'on';
            ManualFlag = 1;
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D0',1);
            writeDigitalPin(a,'D1',0);
            writeDigitalPin(a,'D2',0);
            app.LampGreen.Color = [0 1 0];
            app.LampYellow.Color = [1 1 1];
            app.LampRed.Color = [1 1 1];
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D0',0);
            writeDigitalPin(a,'D1',1);
            writeDigitalPin(a,'D2',0);
            app.LampGreen.Color = [1 1 1];
            app.LampYellow.Color = 'y';
            app.LampRed.Color = 'w';
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D0',0);
            writeDigitalPin(a,'D1',0);
            writeDigitalPin(a,'D2',1);
            app.LampGreen.Color = 'w';
            app.LampYellow.Color = 'w';
            app.LampRed.Color = 'r';
        end

        % Button pushed function: AutomaticButton
        function AutomaticButtonPushed(app, event)
            global ManualFlag;
            app.GreenButton.Enable = 'off';
            app.YellowButton.Enable = 'off';
            app.RedButton.Enable = 'off';
            while (1)
                counter = app.EnterDelayTimeInSecondsEditField.Value;
                RedButtonPushed(app,[]);
                while (counter >= 0)
                app.TimeLabel.Text = num2str(counter);
                counter = counter - 1;
                pause(1); 
                end
                YellowButtonPushed(app,[]);
                counter = app.EnterDelayTimeInSecondsEditField.Value;
                while (counter >= 0)
                app.TimeLabel.Text = num2str(counter);
                counter = counter - 1;
                pause(1); 
                end
                GreenButtonPushed(app,[]);
                counter = app.EnterDelayTimeInSecondsEditField.Value;
                while (counter >= 0)
                app.TimeLabel.Text = num2str(counter);
                counter = counter - 1;
                pause(1); 
                end
            end
           
        end

        % Value changed function: EnterDelayTimeInSecondsEditField
        function EnterDelayTimeInSecondsEditFieldValueChanged(app, event)
            value = app.EnterDelayTimeInSecondsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.AutomaticButton = uibutton(app.UIFigure, 'push');
            app.AutomaticButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticButtonPushed, true);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.LampRed = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.LampYellow = uilamp(app.UIFigure);
            app.Lamp3Label = uilabel(app.UIFigure);
            app.Lamp3Label.Text = 'Lamp3';
            app.LampGreen = uilamp(app.UIFigure);
            app.EnterDelayTimeInSecondsLabel = uilabel(app.UIFigure);
            app.EnterDelayTimeInSecondsLabel.Text = {'Enter Delay'; 'Time In Seconds'};
            app.EnterDelayTimeInSecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterDelayTimeInSecondsEditField.ValueChangedFcn = createCallbackFcn(app, @EnterDelayTimeInSecondsEditFieldValueChanged, true);
            app.EnterDelayTimeInSecondsEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrafficLight(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end